classdef appR17mlapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ManualTab                     matlab.ui.container.Tab
        BCIUVGLabel                   matlab.ui.control.Label
        RodrigoRaldaGLabel            matlab.ui.control.Label
        SeleccioneClaseButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_1                      matlab.ui.control.ToggleButton
        Button_2                      matlab.ui.control.ToggleButton
        Button_3                      matlab.ui.control.ToggleButton
        Button_4                      matlab.ui.control.ToggleButton
        Button_5                      matlab.ui.control.ToggleButton
        ConfiguracinInicialButton     matlab.ui.control.ToggleButton
        SeleccioneRobotDropDownLabel  matlab.ui.control.Label
        SeleccioneRobotDropDown       matlab.ui.control.DropDown
        ElijaunodelostresrobotsquedeseacontrolarLabel  matlab.ui.control.Label
        PresioneelcomandoquedeseaqueejecuteelrobotLabel  matlab.ui.control.Label
        BienvenidoalcontrolmanualdelostresrobotsdisponiblesLabel  matlab.ui.control.Label
        ElijaunodelostresrobotsquedeseacontrolarLabel_2  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Clase1Mueveelrobota0gradosenelplanoXYLabel  matlab.ui.control.Label
        Clase2Mueveelrobota180gradosenelplanoXYLabel  matlab.ui.control.Label
        Clase3Mueveelrobota45gradosenelplanoXYLabel  matlab.ui.control.Label
        Clase4Mueveelrobota135gradosenelplanoXYLabel  matlab.ui.control.Label
        Clase5Mueveelrobota90gradosenelplanoXYLabel  matlab.ui.control.Label
        AutomticoTab                  matlab.ui.container.Tab
        ClaseActualEditFieldLabel     matlab.ui.control.Label
        ClaseActualEditField          matlab.ui.control.NumericEditField
        figFpz                        matlab.ui.control.UIAxes
        figPz                         matlab.ui.control.UIAxes
        figEog                        matlab.ui.control.UIAxes
        figEmg                        matlab.ui.control.UIAxes
        TabGroup2                     matlab.ui.container.TabGroup
        InicioTab                     matlab.ui.container.Tab
        ElresultadodelclasificadorsemuestraenlapartesuperiorLabel  matlab.ui.control.Label
        Las4sealesdeentradasegraficanentiemporealLabel  matlab.ui.control.Label
        ElresultadodelclasificadorsemuestraenlapartesuperiorLabel_2  matlab.ui.control.Label
        ElresultadodelclasificadorsemuestraenlapartesuperiorLabel_3  matlab.ui.control.Label
        DesplegarCaractersticasTab    matlab.ui.container.Tab
        FPZLabel                      matlab.ui.control.Label
        PZLabel                       matlab.ui.control.Label
        EOGLabel                      matlab.ui.control.Label
        EMGLabel                      matlab.ui.control.Label
        MAVEditFieldLabel             matlab.ui.control.Label
        MAVEditField                  matlab.ui.control.NumericEditField
        ZCEditFieldLabel              matlab.ui.control.Label
        ZCEditField                   matlab.ui.control.NumericEditField
        MMDEditFieldLabel             matlab.ui.control.Label
        MMDEditField                  matlab.ui.control.NumericEditField
        BPEditFieldLabel              matlab.ui.control.Label
        BPEditField                   matlab.ui.control.NumericEditField
        KURTEditFieldLabel            matlab.ui.control.Label
        KURTEditField                 matlab.ui.control.NumericEditField
        MAVEditField_2Label           matlab.ui.control.Label
        MAVEditField_2                matlab.ui.control.NumericEditField
        ZCEditField_2Label            matlab.ui.control.Label
        ZCEditField_2                 matlab.ui.control.NumericEditField
        MMDEditField_2Label           matlab.ui.control.Label
        MMDEditField_2                matlab.ui.control.NumericEditField
        BPEditField_2Label            matlab.ui.control.Label
        BPEditField_2                 matlab.ui.control.NumericEditField
        KURTEditField_2Label          matlab.ui.control.Label
        KURTEditField_2               matlab.ui.control.NumericEditField
        MAVEditField_3Label           matlab.ui.control.Label
        MAVEditField_3                matlab.ui.control.NumericEditField
        ZCEditField_3Label            matlab.ui.control.Label
        ZCEditField_3                 matlab.ui.control.NumericEditField
        MMDEditField_3Label           matlab.ui.control.Label
        MMDEditField_3                matlab.ui.control.NumericEditField
        BPEditField_3Label            matlab.ui.control.Label
        BPEditField_3                 matlab.ui.control.NumericEditField
        KURTEditField_3Label          matlab.ui.control.Label
        KURTEditField_3               matlab.ui.control.NumericEditField
        MAVEditField_4Label           matlab.ui.control.Label
        MAVEditField_4                matlab.ui.control.NumericEditField
        TiemposSliderLabel            matlab.ui.control.Label
        TiemposSlider                 matlab.ui.control.Slider
        SeleccioneladuracindelaventanadegrabacinLabel  matlab.ui.control.Label
        IniciargrabacinSwitchLabel    matlab.ui.control.Label
        IniciargrabacinSwitch         matlab.ui.control.Switch
        MovimientoSwitchLabel         matlab.ui.control.Label
        MovimientoSwitch              matlab.ui.control.Switch
    end

    
    methods (Access = private)
        
% %         function graficar(nClase)
% %             %value1 es el tipo de robot y nClase el numero de Clase que
% %             %se desea graficar
% % %             load('robotData.mat')
% % %             load('ur5Data.mat')
% % %             load('pumaData.mat')
% %             value1 = app.SeleccioneRobotDropDown.Value;
% %             if strcmp( value1,'Puma') 
% %                 p560.plot(Qpuma{1,nClase}) 
% %             elseif strcmp( value1,'UR5')
% %                 ur5.plot(Qur5{1,nClase}) 
% %             else
% %                 r17.plot(Qtot{1,nClase}) 
% %             end
% %                 
% %         end
    end
    
    methods (Access = public)
        
         function graficar(app, nClase, flag)
            % nClase el numero de Clase que
            %se desea graficar y flag es 0 si se grafica conf inicial y 1
            %si se grafica toda la trayectoria
            load('robotData.mat')
            load('ur5Data.mat')
            load('pumaData.mat')
            value1 = app.SeleccioneRobotDropDown.Value;
            value2 = app.MovimientoSwitch.Value;
            qR = [0 0 0 0 0 0];
            
            if flag == 1 %Trayectoria
                if strcmp( value2,'Joint') %Junta por junta
                    qR(nClase+1)=pi/4;
                    if strcmp( value1,'Puma') 
                        clf;
                        p560.plot3d(qR) 
                    elseif strcmp( value1,'UR5')
                        ur5.plot(qR)
                    else
                        r17.plot(qR) 
                    end
                    
                else %Pick nplace
                    if strcmp( value1,'Puma') 
                        clf;
                        p560.plot3d(Qpuma{1,nClase}) 
                    elseif strcmp( value1,'UR5')
                        ur5.plot(Qur5{1,nClase})
                    else
                        r17.plot(Qtot{1,nClase}) 
                    end
                end
                
            
            else  %seleccion robot
                if strcmp( value1,'Puma') 
                    close(figure(1));
                    p560.plot3d(Qpuma{1,nClase}(1,:)) 
                elseif strcmp( value1,'UR5')
                    close(figure(1));
                    ur5.plot(Qur5{1,nClase}(1,:))
                else
                    close(figure(1));
                    r17.plot(Qtot{1,nClase}(1,:)) 
                end
            end
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IniciargrabacinSwitch
        function IniciargrabacinSwitchValueChanged(app, event)
            value = app.IniciargrabacinSwitch.Value;
            movegui('northwest');
            load('data_mod')%load('data_1')
            load('trainedModelSVM3.mat')
            load('robotData.mat')
            load('ur5Data.mat')
            load('pumaData.mat')
            largoBuffer = 1;
            seleccion = app.TiemposSlider.Value;
            Epocas = round(seleccion)*100;%3000
            L = largoBuffer*Epocas;
            data = zeros(4,L);
            etiquetasSVM = zeros(757,1);
            contEpocs = 1;
            t=linspace(0, Epocas/100, L);
                
            flag=0;
            
            i=0;
          
          
            if strcmp( value,'On')
            %%Serial****************************************************
                 if ~isempty(instrfind);
                  fclose(instrfind);
                  delete(instrfind);
                  clear s
                 end
                 
                s = serial('COM11', 'BaudRate',115200);
                fopen(s);
            %***********************************************************  
            
                flag =1;
                
                h1 = plot(app.figFpz, t, zeros(1,L))
                h2 = plot(app.figPz, t, zeros(1,L))
                h3 = plot(app.figEog, t, zeros(1,L))
                h4 = plot(app.figEmg, t, zeros(1,L))
                
                ylim(app.figFpz, [min(fpz) max(fpz)]);
                ylim(app.figPz, [min(pz) max(pz)]);
                ylim(app.figEog, [min(eog) max(eog)]);
                ylim(app.figEmg, [min(emg) max(emg)]);
                while flag==1
                    lec = fscanf(s,'%d');
                    if lec==1
                     
                        value = app.IniciargrabacinSwitch.Value;
                        if strcmp( value,'Off')
                            break
                        end
                        
                        i = i+1;
                        n = mod(i,Epocas);
                        
                        if n == 0
                            n = Epocas;
                        end
                        
                        data(:,n)=[fpz(i);
                                    pz(i);
                                    eog(i);
                                    emg(i)];
                                
                        %*****************Figuras
                   
                        h1.YData(n) = fpz(i);
                        h2.YData(n) = pz(i);
                        h3.YData(n) = eog(i);
                        h4.YData(n) = emg(i);
                        drawnow limitrate
                        
                        if n == Epocas
                           % Calcular metricas
%                             media = [mean(abs(data(1,:))), metricas(data(1,:),0,0), MMD(data(1,:,Epocas), bandpower(data(1,:)), kurtosis(data(1,:)),...
%                                     mean(abs(data(2,:))), metricas(data(2,:),0,0), MMD(data(2,:),Epocas), bandpower(data(2,:)), kurtosis(data(2,:)),...
%                                     mean(abs(data(3,:))), metricas(data(3,:),0,0), MMD(data(1,:),Epocas), bandpower(data(3,:)), kurtosis(data(3,:)),...
%                                     mean(abs(data(4,:)))];
                            media = [mean(abs(data(1,:))), metricas(data(1,:),0,0), MMD(data(1,:),Epocas), bandpower(data(1,:)), kurtosis(data(1,:)),...
                                    mean(abs(data(2,:))), metricas(data(2,:),0,0), MMD(data(2,:),Epocas), bandpower(data(2,:)), kurtosis(data(2,:)),...
                                    mean(abs(data(3,:))), metricas(data(3,:),0,0), MMD(data(1,:),Epocas), bandpower(data(3,:)), kurtosis(data(3,:)),...
                                    mean(abs(data(4,:)))];
                            etiquetasSVM(contEpocs)= trainedModelSVM3.predictFcn(media);
                            
                            %etiquetasSVM(contEpocs)
                            app.MAVEditField.Value = media(1,1);
                            app.ZCEditField.Value = media(1,2);
                            app.MMDEditField.Value = media(1,3);
                            app.BPEditField.Value = media(1,4);
                            app.KURTEditField.Value = media(1,5);
                            
                            app.MAVEditField_2.Value = media(1,6);
                            app.ZCEditField_2.Value = media(1,7);
                            app.MMDEditField_2.Value = media(1,8);
                            app.BPEditField_2.Value = media(1,9);
                            app.KURTEditField_2.Value = media(1,10);
                            
                            app.MAVEditField_3.Value = media(1,11);
                            app.ZCEditField_3.Value = media(1,12);
                            app.MMDEditField_3.Value = media(1,13);
                            app.BPEditField_3.Value = media(1,14);
                            app.KURTEditField_3.Value = media(1,15);
                            
                            app.MAVEditField_4.Value = media(1,16);
                            
                            %Robot
                            if media(1,1)>5 &&  media(1,6)>5
                                app.ClaseActualEditField.Value = etiquetasSVM(contEpocs);
                                graficar(app, etiquetasSVM(contEpocs),1)
                                
                            else
                               app.ClaseActualEditField.Value = 0;
                            end
                            
                           % r17.plot(Qtot{1,etiquetasSVM(contEpocs)})
       
                            contEpocs = contEpocs+1;
                            
                        end
                    end
                end
                   
            else
                flag = 0;
            end
        end

        % Selection changed function: SeleccioneClaseButtonGroup_2
        function SeleccioneClaseButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SeleccioneClaseButtonGroup_2.SelectedObject;
            value = app.IniciargrabacinSwitch.Value;
            movegui('northwest');
            load('robotData.mat')
            load('ur5Data.mat')
            load('pumaData.mat')
            
            if strcmp( value,'Off') 

                if selectedButton == app.Button_1
                    %value1 = app.SeleccioneRobotDropDown.Value;
                    graficar(app, 1,1);
                    %r17.plot(Qtot{1,1})
                    
                elseif selectedButton == app.Button_2
                    %value1 = app.SeleccioneRobotDropDown.Value;
                    graficar(app, 2,1);
                    %r17.plot(Qtot{1,2})

                elseif selectedButton == app.Button_3
                    %r17.plot(Qtot{1,3})
                    graficar(app, 3,1);
                elseif selectedButton == app.Button_4
                    %r17.plot(Qtot{1,4})
                    graficar(app, 4,1);
                elseif selectedButton == app.Button_5
                    %r17.plot(Qtot{1,5})   
                    graficar(app, 5,1);
                    
                else
                    graficar(app, 1,0); 
                end
            end
        end

        % Callback function
        function DescripcinPanelSizeChanged(app, event)
            position = app.DescripcinPanel.Position;
        end

        % Callback function
        function DesplegarCaractersticasSwitchValueChanged(app, event)
            value = app.DesplegarCaractersticasSwitch.Value;
        end

        % Value changed function: SeleccioneRobotDropDown
        function SeleccioneRobotDropDownValueChanged(app, event)
            value1 = app.SeleccioneRobotDropDown.Value;
            
            if strcmp( value1,'R17') 
                app.Image.ImageSource = 'r17.PNG';
                graficar(app, 1,0);
            elseif strcmp( value1,'UR5') 
                app.Image.ImageSource = 'ur5Joints.PNG';
                graficar(app, 1,0);
            elseif strcmp( value1,'Puma') 
                app.Image.ImageSource = 'puma560Joints.PNG';
                graficar(app, 1,0);
            end
            
        end

        % Value changed function: TiemposSlider
        function TiemposSliderValueChanged(app, event)
            value = app.TiemposSlider.Value;
            
%             if value == 30
%                 app.ClaseActualEditField.Value = 2;
%             elseif value == 20
%                 app.ClaseActualEditField.Value = 3;
%             elseif value == 5
%                 app.ClaseActualEditField.Value = 4;
%             else
%                 app.ClaseActualEditField.Value = value;
%             end
        end

        % Value changed function: MovimientoSwitch
        function MovimientoSwitchValueChanged(app, event)
            value = app.MovimientoSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.BCIUVGLabel = uilabel(app.ManualTab);
            app.BCIUVGLabel.Text = 'BCI - UVG';
            app.RodrigoRaldaGLabel = uilabel(app.ManualTab);
            app.RodrigoRaldaGLabel.Text = 'Rodrigo Ralda G.';
            app.SeleccioneClaseButtonGroup_2 = uibuttongroup(app.ManualTab);
            app.SeleccioneClaseButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SeleccioneClaseButtonGroup_2SelectionChanged, true);
            app.SeleccioneClaseButtonGroup_2.Title = 'Seleccione Clase';
            app.Button_1 = uitogglebutton(app.SeleccioneClaseButtonGroup_2);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uitogglebutton(app.SeleccioneClaseButtonGroup_2);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.SeleccioneClaseButtonGroup_2);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.SeleccioneClaseButtonGroup_2);
            app.Button_4.Text = '4';
            app.Button_5 = uitogglebutton(app.SeleccioneClaseButtonGroup_2);
            app.Button_5.Text = '5';
            app.ConfiguracinInicialButton = uitogglebutton(app.SeleccioneClaseButtonGroup_2);
            app.ConfiguracinInicialButton.Text = 'Configuración Inicial';
            app.SeleccioneRobotDropDownLabel = uilabel(app.ManualTab);
            app.SeleccioneRobotDropDownLabel.Text = 'Seleccione Robot';
            app.SeleccioneRobotDropDown = uidropdown(app.ManualTab);
            app.SeleccioneRobotDropDown.Items = {'Puma', 'UR5', 'R17'};
            app.SeleccioneRobotDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccioneRobotDropDownValueChanged, true);
            app.SeleccioneRobotDropDown.Value = 'R17';
            app.ElijaunodelostresrobotsquedeseacontrolarLabel = uilabel(app.ManualTab);
            app.ElijaunodelostresrobotsquedeseacontrolarLabel.Text = 'Elija uno de los tres robots que desea controlar.';
            app.PresioneelcomandoquedeseaqueejecuteelrobotLabel = uilabel(app.ManualTab);
            app.PresioneelcomandoquedeseaqueejecuteelrobotLabel.Text = 'Presione el comando que desea que ejecute el robot.';
            app.BienvenidoalcontrolmanualdelostresrobotsdisponiblesLabel = uilabel(app.ManualTab);
            app.BienvenidoalcontrolmanualdelostresrobotsdisponiblesLabel.Text = 'Bienvenido al control manual de los tres robots disponibles.';
            app.ElijaunodelostresrobotsquedeseacontrolarLabel_2 = uilabel(app.ManualTab);
            app.ElijaunodelostresrobotsquedeseacontrolarLabel_2.Text = 'Si desea controlar el robot de manera automática cambie de pestaña e inicie la grabación.';
            app.Image = uiimage(app.ManualTab);
            app.Image.ImageSource = 'r17.PNG';
            app.Clase1Mueveelrobota0gradosenelplanoXYLabel = uilabel(app.ManualTab);
            app.Clase1Mueveelrobota0gradosenelplanoXYLabel.Text = 'Clase 1 - Mueve el robot a 0 grados en el plano XY';
            app.Clase2Mueveelrobota180gradosenelplanoXYLabel = uilabel(app.ManualTab);
            app.Clase2Mueveelrobota180gradosenelplanoXYLabel.Text = 'Clase 2 - Mueve el robot a 180 grados en el plano XY';
            app.Clase3Mueveelrobota45gradosenelplanoXYLabel = uilabel(app.ManualTab);
            app.Clase3Mueveelrobota45gradosenelplanoXYLabel.Text = 'Clase 3 - Mueve el robot a 45 grados en el plano XY';
            app.Clase4Mueveelrobota135gradosenelplanoXYLabel = uilabel(app.ManualTab);
            app.Clase4Mueveelrobota135gradosenelplanoXYLabel.Text = 'Clase 4 - Mueve el robot a 135 grados en el plano XY';
            app.Clase5Mueveelrobota90gradosenelplanoXYLabel = uilabel(app.ManualTab);
            app.Clase5Mueveelrobota90gradosenelplanoXYLabel.Text = 'Clase 5 - Mueve el robot a 90 grados en el plano XY';
            app.AutomticoTab = uitab(app.TabGroup);
            app.AutomticoTab.Title = 'Automático';
            app.ClaseActualEditFieldLabel = uilabel(app.AutomticoTab);
            app.ClaseActualEditFieldLabel.Text = 'Clase Actual';
            app.ClaseActualEditField = uieditfield(app.AutomticoTab, 'numeric');
            app.figFpz = uiaxes(app.AutomticoTab);
            title(app.figFpz, 'Fpz')
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.figPz = uiaxes(app.AutomticoTab);
            title(app.figPz, 'Pz')
            xlabel(app.figPz, 't (s)')
            ylabel(app.figPz, 'uV')
            app.figEog = uiaxes(app.AutomticoTab);
            title(app.figEog, 'EOG')
            xlabel(app.figEog, 't (s)')
            ylabel(app.figEog, 'uV')
            app.figEmg = uiaxes(app.AutomticoTab);
            title(app.figEmg, 'EMG')
            xlabel(app.figEmg, 't (s)')
            ylabel(app.figEmg, 'uV')
            app.TabGroup2 = uitabgroup(app.AutomticoTab);
            app.InicioTab = uitab(app.TabGroup2);
            app.InicioTab.Title = 'Inicio';
            app.ElresultadodelclasificadorsemuestraenlapartesuperiorLabel = uilabel(app.InicioTab);
            app.ElresultadodelclasificadorsemuestraenlapartesuperiorLabel.Text = 'El resultado del clasificador se muestra en la parte superior.';
            app.Las4sealesdeentradasegraficanentiemporealLabel = uilabel(app.InicioTab);
            app.Las4sealesdeentradasegraficanentiemporealLabel.Text = 'Las 4 señales de entrada se grafican en tiempo real.';
            app.ElresultadodelclasificadorsemuestraenlapartesuperiorLabel_2 = uilabel(app.InicioTab);
            app.ElresultadodelclasificadorsemuestraenlapartesuperiorLabel_2.Text = 'Bienvenido al modo automático de lectura, clasificación y ejecución de comandos en tiempo real.';
            app.ElresultadodelclasificadorsemuestraenlapartesuperiorLabel_3 = uilabel(app.InicioTab);
            app.ElresultadodelclasificadorsemuestraenlapartesuperiorLabel_3.Text = 'Si desea observar las caracteríticas que se utilizan para clasificar, cambie de pestaña.';
            app.DesplegarCaractersticasTab = uitab(app.TabGroup2);
            app.DesplegarCaractersticasTab.Title = 'Desplegar Características';
            app.FPZLabel = uilabel(app.DesplegarCaractersticasTab);
            app.FPZLabel.Text = 'FPZ';
            app.PZLabel = uilabel(app.DesplegarCaractersticasTab);
            app.PZLabel.Text = 'PZ';
            app.EOGLabel = uilabel(app.DesplegarCaractersticasTab);
            app.EOGLabel.Text = 'EOG';
            app.EMGLabel = uilabel(app.DesplegarCaractersticasTab);
            app.EMGLabel.Text = 'EMG';
            app.MAVEditFieldLabel = uilabel(app.DesplegarCaractersticasTab);
            app.MAVEditFieldLabel.Text = 'MAV';
            app.MAVEditField = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.ZCEditFieldLabel = uilabel(app.DesplegarCaractersticasTab);
            app.ZCEditFieldLabel.Text = 'ZC';
            app.ZCEditField = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.MMDEditFieldLabel = uilabel(app.DesplegarCaractersticasTab);
            app.MMDEditFieldLabel.Text = 'MMD';
            app.MMDEditField = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.BPEditFieldLabel = uilabel(app.DesplegarCaractersticasTab);
            app.BPEditFieldLabel.Text = 'BP';
            app.BPEditField = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.KURTEditFieldLabel = uilabel(app.DesplegarCaractersticasTab);
            app.KURTEditFieldLabel.Text = 'KURT';
            app.KURTEditField = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.MAVEditField_2Label = uilabel(app.DesplegarCaractersticasTab);
            app.MAVEditField_2Label.Text = 'MAV';
            app.MAVEditField_2 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.ZCEditField_2Label = uilabel(app.DesplegarCaractersticasTab);
            app.ZCEditField_2Label.Text = 'ZC';
            app.ZCEditField_2 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.MMDEditField_2Label = uilabel(app.DesplegarCaractersticasTab);
            app.MMDEditField_2Label.Text = 'MMD';
            app.MMDEditField_2 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.BPEditField_2Label = uilabel(app.DesplegarCaractersticasTab);
            app.BPEditField_2Label.Text = 'BP';
            app.BPEditField_2 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.KURTEditField_2Label = uilabel(app.DesplegarCaractersticasTab);
            app.KURTEditField_2Label.Text = 'KURT';
            app.KURTEditField_2 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.MAVEditField_3Label = uilabel(app.DesplegarCaractersticasTab);
            app.MAVEditField_3Label.Text = 'MAV';
            app.MAVEditField_3 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.ZCEditField_3Label = uilabel(app.DesplegarCaractersticasTab);
            app.ZCEditField_3Label.Text = 'ZC';
            app.ZCEditField_3 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.MMDEditField_3Label = uilabel(app.DesplegarCaractersticasTab);
            app.MMDEditField_3Label.Text = 'MMD';
            app.MMDEditField_3 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.BPEditField_3Label = uilabel(app.DesplegarCaractersticasTab);
            app.BPEditField_3Label.Text = 'BP';
            app.BPEditField_3 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.KURTEditField_3Label = uilabel(app.DesplegarCaractersticasTab);
            app.KURTEditField_3Label.Text = 'KURT';
            app.KURTEditField_3 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.MAVEditField_4Label = uilabel(app.DesplegarCaractersticasTab);
            app.MAVEditField_4Label.Text = 'MAV';
            app.MAVEditField_4 = uieditfield(app.DesplegarCaractersticasTab, 'numeric');
            app.TiemposSliderLabel = uilabel(app.AutomticoTab);
            app.TiemposSliderLabel.Text = 'Tiempo (s)';
            app.TiemposSlider = uislider(app.AutomticoTab);
            app.TiemposSlider.Limits = [1 30];
            app.TiemposSlider.MajorTicks = [1 5 10 15 20 25 30];
            app.TiemposSlider.ValueChangedFcn = createCallbackFcn(app, @TiemposSliderValueChanged, true);
            app.TiemposSlider.Value = 1;
            app.SeleccioneladuracindelaventanadegrabacinLabel = uilabel(app.AutomticoTab);
            app.SeleccioneladuracindelaventanadegrabacinLabel.Text = 'Seleccione la duración de la ventana de grabación';
            app.IniciargrabacinSwitchLabel = uilabel(app.UIFigure);
            app.IniciargrabacinSwitchLabel.Text = 'Iniciar grabación';
            app.IniciargrabacinSwitch = uiswitch(app.UIFigure, 'slider');
            app.IniciargrabacinSwitch.ValueChangedFcn = createCallbackFcn(app, @IniciargrabacinSwitchValueChanged, true);
            app.MovimientoSwitchLabel = uilabel(app.UIFigure);
            app.MovimientoSwitchLabel.Text = 'Movimiento';
            app.MovimientoSwitch = uiswitch(app.UIFigure, 'slider');
            app.MovimientoSwitch.Items = {'Joint', 'PickNPlace'};
            app.MovimientoSwitch.ValueChangedFcn = createCallbackFcn(app, @MovimientoSwitchValueChanged, true);
            app.MovimientoSwitch.Value = 'Joint';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appR17mlapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end